classdef interfaceapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AboutTab                        matlab.ui.container.Tab
        MillerBladeStructLabel          matlab.ui.control.Label
        MillerBladeStructDescription    matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        BladeStructureTab               matlab.ui.container.Tab
        BladeStructLabel                matlab.ui.control.Label
        GenerateBladeStructurePanel     matlab.ui.container.Panel
        BrowseBladePropertiesButton     matlab.ui.control.Button
        BladePropertiesEditFieldLabel   matlab.ui.control.Label
        BladePropertiesEditField        matlab.ui.control.EditField
        BrowseYoungsModulusButton       matlab.ui.control.Button
        ModulusofElasticityEditFieldLabel  matlab.ui.control.Label
        ModulusofElasticityEditField    matlab.ui.control.EditField
        BrowseMassButton                matlab.ui.control.Button
        MassEditFieldLabel              matlab.ui.control.Label
        MassEditField                   matlab.ui.control.EditField
        BrowseBladeStructureOutputButton  matlab.ui.control.Button
        BladeStructureOutputEditFieldLabel  matlab.ui.control.Label
        BladeStructureOutputEditField   matlab.ui.control.EditField
        GenerateBladeStructureButton    matlab.ui.control.Button
        GenerateBladeStructureTextArea  matlab.ui.control.TextArea
        BrowsePitchButton               matlab.ui.control.Button
        PitchEditFieldLabel             matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.EditField
        CustomMaterialPropertiesPanel   matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        ModulusofElasticityEditField_2Label  matlab.ui.control.Label
        ModulusofElasticityUserMatEditField  matlab.ui.control.EditField
        DensityEditFieldLabel           matlab.ui.control.Label
        DensityUserMatEditField         matlab.ui.control.EditField
        BladeAnalysisTab                matlab.ui.container.Tab
        BladeAnalysisLabel              matlab.ui.control.Label
        StructuralAnalysisPanel         matlab.ui.container.Panel
        BrowseBladeStructureButton      matlab.ui.control.Button
        BladeStructureEditFieldLabel    matlab.ui.control.Label
        BladeStructureEditField         matlab.ui.control.EditField
        BrowseStructuralAnalysisOutputButton  matlab.ui.control.Button
        StructuralAnalysisOutputEditFieldLabel  matlab.ui.control.Label
        StructuralAnalysisOutputEditField  matlab.ui.control.EditField
        BrowseInputLoadsButton          matlab.ui.control.Button
        InputLoadsEditFieldLabel        matlab.ui.control.Label
        InputLoadsEditField             matlab.ui.control.EditField
        GenerateDeflectionsButton       matlab.ui.control.Button
        GenerateDeflectionsTextArea     matlab.ui.control.TextArea
        GenerateEigenmodesButton        matlab.ui.control.Button
        GenerateEigenmodesTextArea      matlab.ui.control.TextArea
        PropertyGeneratorTab            matlab.ui.container.Tab
        PropertyGeneratorLabel          matlab.ui.control.Label
        PropertyGeneratorOptionsPanel   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        SimpleComplexSwitch             matlab.ui.control.Switch
        SwitchLabel                     matlab.ui.control.Label
        TwistSwitch                     matlab.ui.control.Switch
        Label_2                         matlab.ui.control.Label
        ChordSwitch                     matlab.ui.control.Switch
        Label_3                         matlab.ui.control.Label
        SaveSwitch                      matlab.ui.control.Switch
        GeneratePropertiesPanel         matlab.ui.container.Panel
        AirfoilEditFieldLabel           matlab.ui.control.Label
        AirfoilEditField                matlab.ui.control.EditField
        BrowseAirfoilButton             matlab.ui.control.Button
        BrowseTwistButton               matlab.ui.control.Button
        TwistDistributionEditFieldLabel  matlab.ui.control.Label
        TwistDistributionEditField      matlab.ui.control.EditField
        BrowseChordButton               matlab.ui.control.Button
        ChordDistributionEditFieldLabel  matlab.ui.control.Label
        ChordDistributionEditField      matlab.ui.control.EditField
        BrowseNegativeSpaceButton       matlab.ui.control.Button
        NegativeSpaceEditFieldLabel     matlab.ui.control.Label
        NegativeSpaceEditField          matlab.ui.control.EditField
        BrowseSaveAirfoilButton         matlab.ui.control.Button
        PropertyGeneratorOutputEditFieldLabel  matlab.ui.control.Label
        PropertyGeneratorOutputEditField  matlab.ui.control.EditField
        GeneratePropertiesButton        matlab.ui.control.Button
        GeneratePropertiesTextArea      matlab.ui.control.TextArea
        XTurbBackendTab                 matlab.ui.container.Tab
        XTurbBackendLabel               matlab.ui.control.Label
        ConverterPanel                  matlab.ui.container.Panel
        BrowseXTurb_OutputdatButton     matlab.ui.control.Button
        XTurb_OutputdatLabel            matlab.ui.control.Label
        XTurb_OutputdatEditField        matlab.ui.control.EditField
        BrowseXTurbOutputButton         matlab.ui.control.Button
        XTurbOutputEditFieldLabel       matlab.ui.control.Label
        XTurbOutputEditField            matlab.ui.control.EditField
        ConvertButton                   matlab.ui.control.Button
        ConvertTextArea                 matlab.ui.control.TextArea
        Converter2Panel                 matlab.ui.container.Panel
        BrowseXTurbinpButton            matlab.ui.control.Button
        XTurbinpEditFieldLabel          matlab.ui.control.Label
        XTurbinpEditField               matlab.ui.control.EditField
        BrowseXTurbOutput2Button        matlab.ui.control.Button
        XTurbOutputEditField_2Label     matlab.ui.control.Label
        XTurbOutput2EditField           matlab.ui.control.EditField
        Convert2Button                  matlab.ui.control.Button
        Convert2TextArea                matlab.ui.control.TextArea
        XTurbBackend2Tab                matlab.ui.container.Tab
        XTurbBackend2Label              matlab.ui.control.Label
        ExtractLoadsPanel               matlab.ui.container.Panel
        BrowseExtractFromButton         matlab.ui.control.Button
        ExtractFromEditFieldLabel       matlab.ui.control.Label
        ExtractFromEditField            matlab.ui.control.EditField
        BrowseExtractOutputButton       matlab.ui.control.Button
        ExtractOutputEditFieldLabel     matlab.ui.control.Label
        ExtractOutputEditField          matlab.ui.control.EditField
        ExtractButton                   matlab.ui.control.Button
        ExtractTextArea                 matlab.ui.control.TextArea
        PlotTab                         matlab.ui.container.Tab
        PlotLabel                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % User-written properties and methods
    properties(Access = public)
        
        % Property generator
        propgenmode;
        twistmode;
        chordmode;
        savemode;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize certain properties
            app.propgenmode = "Complex Geometry";
            app.twistmode = "User-defined Twist";
            app.chordmode = "User-defined Chord";
            app.savemode = "Save Properties to File";
            
            % add image path
            app.Image.ImageSource =...
                "Miller-Blade-Struct_resources/" + ...
                "images/miller-blade-struct-meme.png";
        end

        % Value changed function: SimpleComplexSwitch
        function SimpleComplexSwitchValueChanged(app, event)
            value = app.SimpleComplexSwitch.Value;
            app.propgenmode = value;
            
            if value == "Solid Geometry"
                app.NegativeSpaceEditField.Visible = 'off';
                %                 app.NegativeSpaceEditField.Value = "";
            elseif value == "Complex Geometry"
                app.NegativeSpaceEditField.Visible = 'on';
            end
        end

        % Value changed function: TwistSwitch
        function TwistSwitchValueChanged(app, event)
            value = app.TwistSwitch.Value;
            app.twistmode = value;
            
            if value == "No Twist"
                app.TwistDistributionEditField.Visible = 'off';
                app.ChordDistributionEditField.Visible = 'off';
                app.ChordSwitch.Value = app.ChordSwitch.Items(2);
                app.chordmode = "Chord = 1";
            elseif value == "User-defined Twist"
                app.TwistDistributionEditField.Visible = 'on';
                app.ChordDistributionEditField.Visible = 'on';
                app.ChordSwitch.Value = app.ChordSwitch.Items(1);
                app.chordmode = "User-defined Chord";
            end
        end

        % Value changed function: ChordSwitch
        function ChordSwitchValueChanged(app, event)
            value = app.ChordSwitch.Value;
            app.chordmode = value;
            
            if value == "Chord = 1"
                app.ChordDistributionEditField.Visible = 'off';
                app.TwistDistributionEditField.Visible = 'off';
                app.TwistSwitch.Value = app.TwistSwitch.Items(2);
                app.twistmode = "No Twist";
            elseif value == "User-defined Chord"
                app.ChordDistributionEditField.Visible = 'on';
                app.TwistDistributionEditField.Visible = 'on';
                app.TwistSwitch.Value = app.TwistSwitch.Items(1);
                app.twistmode = "User-defined Twist";
            end
        end

        % Value changed function: SaveSwitch
        function SaveSwitchValueChanged(app, event)
            value = app.SaveSwitch.Value;
            app.savemode = value;
            
            if value == "Output Only"
                app.PropertyGeneratorOutputEditField.Visible = 'off';
                %                 app.PropertyGeneratorOutputEditField.Value = "";
            elseif value == "Save Properties to File"
                app.PropertyGeneratorOutputEditField.Visible = 'on';
            end
        end

        % Button pushed function: BrowseAirfoilButton
        function BrowseAirfoilButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.AirfoilEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseTwistButton
        function BrowseTwistButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.TwistDistributionEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseChordButton
        function BrowseChordButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.ChordDistributionEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseNegativeSpaceButton
        function BrowseNegativeSpaceButtonPushed(app, event)
            % write browse path to the edit field
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.NegativeSpaceEditField.Value =...
                strip(strip(strcat(erase(dir, pwd), "/"), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseSaveAirfoilButton
        function BrowseSaveAirfoilButtonPushed(app, event)
            % write browse path to the edit field
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.PropertyGeneratorOutputEditField.Value =...
                strip(strip(strcat(erase(dir, pwd), "/"), 'left', '\'), 'left', '/');
        end

        % Button pushed function: GeneratePropertiesButton
        function GeneratePropertiesButtonPushed(app, event)
            app.GeneratePropertiesTextArea.Value =...
                    "";
            pause(0.05);
            app.GeneratePropertiesTextArea.Value =...
                    "Working...";
            pause(0.01);
            % Process file paths
            airfoilfp = strcat(pwd, "/",...
                erase(app.AirfoilEditField.Value, pwd));
            [fullpath, airfoilname, ext] = fileparts(strcat(airfoilfp));
            twistfp = strcat(pwd, "/",...
                erase(app.TwistDistributionEditField.Value, pwd));
            chordfp = strcat(pwd, "/",...
                erase(app.ChordDistributionEditField.Value, pwd));
            if app.propgenmode == "Solid Geometry"
                negativename = "Solid";
            elseif app.propgenmode == "Complex Geometry"
                negativename = "Complex";
            end
            
            % Define input values, alter file name components
            af = app.AirfoilEditField.Value; % airfoil file path
            naf = app.NegativeSpaceEditField.Value; % negative space dir
            if app.chordmode == "Chord = 1" % chord values
                c = 1;
                chordname = "Chord = 1";
            elseif app.chordmode == "User-defined Chord"
                c = app.ChordDistributionEditField.Value;
                [fullpath, chordname, ext] = fileparts(strcat(chordfp));
            end
            if app.twistmode == "No Twist" % twist values
                beta = 0;
                twistname = "No Twist";
            elseif app.twistmode == "User-defined Twist"
                beta = app.TwistDistributionEditField.Value;
                [fullpath, twistname, ext] = fileparts(strcat(twistfp));
            end
            saveloc = app.PropertyGeneratorOutputEditField.Value; % save dir
            
            % Generate properties
            if app.propgenmode == "Solid Geometry"
                ps = interface.simplepropgen(af, c, beta);
                prop = ps.simpleprop;
            elseif app.propgenmode == "Complex Geometry"
                ps = interface.afpropgen(af, naf, c, beta);
                prop = ps.afprop;
            end
            
            % Format generated properties
            out = cellstr(strcat("[ ", prop(:,1), "    ",...
                prop(:,2), "    ", prop(:,3), "    ",...
                prop(:,4), "    ", prop(:,5),...
                "    ", prop(:,6), "    ", prop(:,7), " ]"));
            
            % Create file name
            filename = strcat(airfoilname, "_", negativename,...
                "_", twistname, "_", chordname);
            filename = strcat(saveloc, filename, ".csv");
            
            % GUI outputs
            if app.savemode == "Output Only"
                app.GeneratePropertiesTextArea.Value = out;
            elseif app.savemode == "Save Properties to File"
                writecell(cellstr(prop), filename);
                app.GeneratePropertiesTextArea.Value =...
                    ["Saved!", string(filename)];
            end
            
            % plot *************************************
            ax = app.UIAxes;
            ax.cla;
            ax.Title.String = erase(strrep(filename, '_', ', '),...
                [saveloc, '.csv']);
            
            sf = '#00c49a';
            
            if app.propgenmode == "Solid Geometry"
                af = ps.af;
                afs = ps.afs;
                % main plot
                plot(ax, af(:,1), af(:,2), 'linewidth', 0.75, 'color', 'black');
                hold(ax, 'on')
                for j=1:1:length(afs(:,1))-1
                    u = sum(afs(j:j+1, 2))/2;
                    l = sum(afs(j:j+1, 4))/2;
                    x = afs([j, j, j+1, j+1], 1);
                    plot(ax, x, [0, u, u, 0], 'color', sf);
                    plot(ax, x, [0, l, l, 0], 'color', sf);
                end
                % plot options
                if contains(app.AirfoilEditField.Value, 'airfoil')
                    ax.YLim = [min(af(:,2))*5., max(af(:,2))*5.];
                else
                    ax.YLim = [-inf, inf];
                end
                ax.XGrid = 'on';
                ax.YGrid = 'on';
                ax.GridLineStyle = ':';
            elseif app.propgenmode == "Complex Geometry"
                afsi = ps.afsi;
                afsi2 = [flipud(afsi(:,1:2)); afsi(:,3:4)];
                nafmsi = ps.nafmsi;
                numnaf = length(nafmsi(1,:))/4;
                % main plot
                plot(ax, afsi2(:,1), afsi2(:,2), 'linewidth', 0.75, 'color', 'black');
                hold(ax, 'on')
                for j=1:1:length(afsi(:,1))-1
                    if rem(j, 5) == 0
                        u = sum(afsi(j:j+1, 2))/2;
                        l = sum(afsi(j:j+1, 4))/2;
                        x = afsi([j, j, j+1, j+1], 1);
                        plot(ax, x, [0, u, u, 0], 'color', sf);
                        plot(ax, x, [0, l, l, 0], 'color', sf);
                    end
                end
                
                index = 1; % index to read 4-column-wide negative airfoil data
                for h=1:1:numnaf
                    plot(ax, nafmsi(:, index), nafmsi(:, index+1), 'r-')
                    plot(ax, nafmsi(:, index+2), nafmsi(:, index+3), 'r-')
                    
                    for j=1:1:length(nafmsi(:,1))-1
                        if rem(j, 5) == 0
                            u = sum(nafmsi(j:j+1, index+1))/2;
                            l = sum(nafmsi(j:j+1, index+3))/2;
                            x = nafmsi([j, j, j+1, j+1], index);
                            plot(ax, x, [0, u, u, 0], 'color', 'red');
                            plot(ax, x, [0, l, l, 0], 'color', 'red');
                        end
                    end
                    
                    % increment index
                    index = index + 4;
                end
                
                % plot options
                if contains(app.AirfoilEditField.Value, 'airfoil')
                    ax.YLim = [min(afsi2(:,2))*5., max(afsi2(:,2))*5.];
                else
                    ax.YLim = [-inf, inf];
                end
                ax.XGrid = 'on';
                ax.YGrid = 'on';
                ax.GridLineStyle = ':';
            end
        end

        % Button pushed function: BrowseXTurb_OutputdatButton
        function BrowseXTurb_OutputdatButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.XTurb_OutputdatEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseXTurbOutputButton
        function BrowseXTurbOutputButtonPushed(app, event)
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.XTurbOutputEditField.Value =...
                strip(strip(strcat(erase(dir, pwd), "/"), 'left', '\'), 'left', '/');
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            app.ConvertTextArea.Value = "";
            pause(0.05);
            app.ConvertTextArea.Value = "Working...";
            pause(0.01);
            filePath = app.XTurb_OutputdatEditField.Value;
            outPath = app.XTurbOutputEditField.Value;
            xs = interface.xturbreader(filePath);
            
            writetable(xs.endtable, strcat(outPath, xs.fileName));
            app.ConvertTextArea.Value =...
                ["Saved!"; strcat(outPath, xs.fileName)];
        end

        % Button pushed function: BrowseExtractFromButton
        function BrowseExtractFromButtonPushed(app, event)
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.ExtractFromEditField.Value =...
                strip(strip(strcat(erase(dir, pwd), "/"), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseExtractOutputButton
        function BrowseExtractOutputButtonPushed(app, event)
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.ExtractOutputEditField.Value =...
                strip(strip(strcat(erase(dir, pwd), "/"), 'left', '\'), 'left', '/');
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            app.ExtractTextArea.Value = "";
            pause(0.05);
            app.ExtractTextArea.Value = "Working...";
            pause(0.01);
            dirPath = app.ExtractFromEditField.Value;
            outPath = app.ExtractOutputEditField.Value;
            dirName = strip(dirPath, '/');
            fileName = strcat("XTurb-Loads_", dirName, ".csv");
            xturbl = interface.xturbloads(dirPath);
            
%             app.ExtractTextArea.Value = "Working...";
%             pause(0.25);
            writecell(cellstr(xturbl), strcat(outPath, fileName));
            app.ExtractTextArea.Value =...
                ["Saved!"; strcat(outPath, fileName)];
        end

        % Button pushed function: BrowseBladePropertiesButton
        function BrowseBladePropertiesButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.BladePropertiesEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseYoungsModulusButton
        function BrowseYoungsModulusButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.ModulusofElasticityEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseMassButton
        function BrowseMassButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.MassEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseBladeStructureOutputButton
        function BrowseBladeStructureOutputButtonPushed(app, event)
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.BladeStructureOutputEditField.Value =...
                strip(strip(strcat(erase(dir, pwd), "/"), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseBladeStructureButton
        function BrowseBladeStructureButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.BladeStructureEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: 
        % BrowseStructuralAnalysisOutputButton
        function BrowseStructuralAnalysisOutputButtonPushed(app, event)
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.StructuralAnalysisOutputEditField.Value =...
                strip(strip(strcat(erase(dir, pwd), "/"), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowsePitchButton
        function BrowsePitchButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.PitchEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseInputLoadsButton
        function BrowseInputLoadsButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.InputLoadsEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: GenerateBladeStructureButton
        function GenerateBladeStructureButtonPushed(app, event)
            app.GenerateBladeStructureTextArea.Value = "";
            pause(0.05);
            app.GenerateBladeStructureTextArea.Value = "Working...";
            pause(0.01);
            
            custom = 0;
            % check if user-defined material in use
            if app.DensityUserMatEditField.Value ~= "" &&...
                    app.ModulusofElasticityUserMatEditField.Value ~= ""
                custom = 1;
            end
            
            % file name components
            bpfp = strcat(pwd, "/",...
                erase(app.BladePropertiesEditField.Value, pwd));
            [fullpath, bpname, ext] = fileparts(strcat(bpfp));
            EEfp = strcat(pwd, "/",...
                erase(app.ModulusofElasticityEditField.Value, pwd));
            [fullpath, EEname, ext] = fileparts(strcat(EEfp));
            
            % file paths
            bp = app.BladePropertiesEditField.Value;
            moe = app.ModulusofElasticityEditField.Value;
            mass = app.MassEditField.Value;
            pitch = app.PitchEditField.Value;
            outPath = app.BladeStructureOutputEditField.Value;
            
            moeum = app.ModulusofElasticityUserMatEditField.Value;
            rhoum = app.DensityUserMatEditField.Value;
            
            % create file name
            if custom
                fileName =...
                    strcat("Blade-Struct_UserMat_", bpname, ".csv");
            else
                fileName =...
                    strcat("Blade-Struct_", EEname, "_", bpname, ".csv");
            end
            
            % call function
            if custom
                bs = interface.bladestructgenusermat(bp, moeum, rhoum, pitch);
            else
                bs = interface.bladestructgen(bp, moe, mass, pitch);
            end
            
            writecell(cellstr(bs), strcat(outPath, fileName));
            app.GenerateBladeStructureTextArea.Value =...
                strcat(["Saved!"; strcat(outPath, fileName)]);
        end

        % Button pushed function: GenerateDeflectionsButton
        function GenerateDeflectionsButtonPushed(app, event)
            app.GenerateDeflectionsTextArea.Value = "";
            pause(0.05);
            app.GenerateDeflectionsTextArea.Value = "Working...";
            pause(0.01);
            
            % file name comps
            bsfp = strcat(pwd, "/",...
                erase(app.BladeStructureEditField.Value, pwd));
            [fullpath, bsname, ext] = fileparts(strcat(bsfp));
            
            % file paths
            bs = app.BladeStructureEditField.Value;
            il = app.InputLoadsEditField.Value;
            outPath = app.StructuralAnalysisOutputEditField.Value;
            bsname = erase(bsname, "Blade-Struct_");
            fileName = strcat("Deflections_", bsname ,".csv");
            
            out = interface.deflecfxf(bs, il);
            
%             app.GenerateDeflectionsTextArea.Value = "Working...";
%             pause(0.25);
            writecell(cellstr(out), strcat(outPath, fileName));
            app.GenerateDeflectionsTextArea.Value =...
                strcat(["Saved!"; strcat(outPath, fileName)]);
        end

        % Button pushed function: GenerateEigenmodesButton
        function GenerateEigenmodesButtonPushed(app, event)
            app.GenerateEigenmodesTextArea.Value = "";
            pause(0.05);
            app.GenerateEigenmodesTextArea.Value = "Working...";
            pause(0.01);
            
            % file name comps
            bsfp = strcat(pwd, "/",...
                erase(app.BladeStructureEditField.Value, pwd));
            [fullpath, bsname, ext] = fileparts(strcat(bsfp));
            
            % file paths
            bs = app.BladeStructureEditField.Value;
            outPath = app.StructuralAnalysisOutputEditField.Value;
            bsname = erase(bsname, "Blade-Struct_");
            fileName = strcat("Eigenmodes_", bsname ,".mat");
            
            out = interface.eigenmode(bs);
            
%             app.GenerateEigenmodesTextArea.Value = "Working...";
%             pause(0.25);
            save(strcat(outPath, fileName), 'out');
            app.GenerateEigenmodesTextArea.Value =...
                strcat(["Saved!"; strcat(outPath, fileName)]);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.DensityUserMatEditField.Value = "";
            app.ModulusofElasticityUserMatEditField.Value = "";
        end

        % Button pushed function: BrowseXTurbinpButton
        function BrowseXTurbinpButtonPushed(app, event)
            % write browse path to the edit field
            [file, folder] = uigetfile("*.*");
            if file==0
                % user pressed cancel
                return
            end
            app.XTurbinpEditField.Value =...
                strip(strip(strcat(erase(folder, pwd), file), 'left', '\'), 'left', '/');
        end

        % Button pushed function: BrowseXTurbOutput2Button
        function BrowseXTurbOutput2ButtonPushed(app, event)
            dir = uigetdir();
            if dir==0
                % user pressed cancel
                return
            end
            app.XTurbOutput2EditField.Value =...
                strip(strip(strcat(erase(dir, pwd), "/"), 'left', '\'), 'left', '/');
        end

        % Button pushed function: Convert2Button
        function Convert2ButtonPushed(app, event)
            app.Convert2TextArea.Value = "";
            pause(0.05);
            app.Convert2TextArea.Value = "Working...";
            pause(0.01);
            
            filePath = app.XTurbinpEditField.Value;
            outPath = app.XTurbOutput2EditField.Value;
            s = interface.xturbinp(filePath);
            
            % Combined output file
            %             header = ["x", "TAPER", "TWIST[RAD]"];
            %             data = [s.taperdistrinterp(:,1),...
            %                 s.taperdistrinterp(:,2), s.twistdistrinterp(:,2)];
            %             endtable = cell2table(num2cell(data),'VariableNames', header);
            %
            %             fileName = strcat("XTurb_inp-", s.name,...
            %                "-jx", string(s.jx), ".csv");
            %
            %             app.Convert2TextArea.Value = "Working...";
            %             pause(0.25);
            %             writetable(endtable, strcat(outPath, fileName));
            %             app.Convert2TextArea.Value =...
            %                 ["Saved!"; strcat(outPath, fileName)];
            
            % Separate taper and twist distributions
            tapername = strcat("TaperDistr_", s.name,...
                "_jx", string(s.jx), ".csv");
            twistname = strcat("TwistDistr_", s.name,...
                "_jx", string(s.jx), ".csv");
            
%             app.Convert2TextArea.Value = "Working...";
%             pause(0.25);
            %             writetable(endtable, strcat(outPath, fileName));
            csvwrite(strcat(outPath, tapername), s.taperdistrinterp);
            csvwrite(strcat(outPath, twistname), s.twistdistrinterp);
            app.Convert2TextArea.Value = ["Saved!";...
                strcat(outPath, tapername);...
                strcat(outPath, twistname)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.MillerBladeStructLabel = uilabel(app.AboutTab);
            app.MillerBladeStructLabel.Text = 'Miller-Blade-Struct v0.X.X';
            app.MillerBladeStructDescription = uitextarea(app.AboutTab);
            app.MillerBladeStructDescription.Value = {''; 'Miller Lab code for turbine blade structures. '; 'Calculates deflections, eigenmodes, and blade properties.Uses XTurb-PSU as a backend to extract aerodynamic loads.'; ''; 'This is a beta version. Please contact me or submit an issue on the github with regards to bug fixes and feature requests.'; ''; 'Supported XTurb Versions: XTurb-PSU-g77, XTurb v1.9 (limited)'; ''; '----------------------------------------------------------------------------------------'; ''; 'Written by: Sean Wang (sjw5651@psu.edu)'; ''; '----------------------------------------------------------------------------------------'; ''};
            app.Image = uiimage(app.AboutTab);
            app.BladeStructureTab = uitab(app.TabGroup);
            app.BladeStructureTab.Title = 'Blade-Structure';
            app.BladeStructLabel = uilabel(app.BladeStructureTab);
            app.BladeStructLabel.Text = 'Blade-Struct';
            app.GenerateBladeStructurePanel = uipanel(app.BladeStructureTab);
            app.GenerateBladeStructurePanel.Title = 'Generate Blade Structure';
            app.BrowseBladePropertiesButton = uibutton(app.GenerateBladeStructurePanel, 'push');
            app.BrowseBladePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBladePropertiesButtonPushed, true);
            app.BrowseBladePropertiesButton.Text = 'Browse';
            app.BladePropertiesEditFieldLabel = uilabel(app.GenerateBladeStructurePanel);
            app.BladePropertiesEditFieldLabel.Text = 'Blade Properties';
            app.BladePropertiesEditField = uieditfield(app.GenerateBladeStructurePanel, 'text');
            app.BrowseYoungsModulusButton = uibutton(app.GenerateBladeStructurePanel, 'push');
            app.BrowseYoungsModulusButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseYoungsModulusButtonPushed, true);
            app.BrowseYoungsModulusButton.Text = 'Browse';
            app.ModulusofElasticityEditFieldLabel = uilabel(app.GenerateBladeStructurePanel);
            app.ModulusofElasticityEditFieldLabel.Text = 'Modulus of Elasticity';
            app.ModulusofElasticityEditField = uieditfield(app.GenerateBladeStructurePanel, 'text');
            app.BrowseMassButton = uibutton(app.GenerateBladeStructurePanel, 'push');
            app.BrowseMassButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMassButtonPushed, true);
            app.BrowseMassButton.Text = 'Browse';
            app.MassEditFieldLabel = uilabel(app.GenerateBladeStructurePanel);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.GenerateBladeStructurePanel, 'text');
            app.BrowseBladeStructureOutputButton = uibutton(app.GenerateBladeStructurePanel, 'push');
            app.BrowseBladeStructureOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBladeStructureOutputButtonPushed, true);
            app.BrowseBladeStructureOutputButton.Text = 'Browse';
            app.BladeStructureOutputEditFieldLabel = uilabel(app.GenerateBladeStructurePanel);
            app.BladeStructureOutputEditFieldLabel.Text = 'Blade Structure Output';
            app.BladeStructureOutputEditField = uieditfield(app.GenerateBladeStructurePanel, 'text');
            app.GenerateBladeStructureButton = uibutton(app.GenerateBladeStructurePanel, 'push');
            app.GenerateBladeStructureButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBladeStructureButtonPushed, true);
            app.GenerateBladeStructureButton.Text = {'Generate'; 'Blade Structure'};
            app.GenerateBladeStructureTextArea = uitextarea(app.GenerateBladeStructurePanel);
            app.BrowsePitchButton = uibutton(app.GenerateBladeStructurePanel, 'push');
            app.BrowsePitchButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePitchButtonPushed, true);
            app.BrowsePitchButton.Text = 'Browse';
            app.PitchEditFieldLabel = uilabel(app.GenerateBladeStructurePanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.GenerateBladeStructurePanel, 'text');
            app.CustomMaterialPropertiesPanel = uipanel(app.BladeStructureTab);
            app.CustomMaterialPropertiesPanel.Title = 'Custom Material Properties';
            app.ClearButton = uibutton(app.CustomMaterialPropertiesPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ModulusofElasticityEditField_2Label = uilabel(app.CustomMaterialPropertiesPanel);
            app.ModulusofElasticityEditField_2Label.Text = 'Modulus of Elasticity';
            app.ModulusofElasticityUserMatEditField = uieditfield(app.CustomMaterialPropertiesPanel, 'text');
            app.DensityEditFieldLabel = uilabel(app.CustomMaterialPropertiesPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityUserMatEditField = uieditfield(app.CustomMaterialPropertiesPanel, 'text');
            app.BladeAnalysisTab = uitab(app.TabGroup);
            app.BladeAnalysisTab.Title = 'Blade-Analysis';
            app.BladeAnalysisLabel = uilabel(app.BladeAnalysisTab);
            app.BladeAnalysisLabel.Text = 'Blade-Analysis';
            app.StructuralAnalysisPanel = uipanel(app.BladeAnalysisTab);
            app.StructuralAnalysisPanel.Title = 'Structural Analysis';
            app.BrowseBladeStructureButton = uibutton(app.StructuralAnalysisPanel, 'push');
            app.BrowseBladeStructureButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseBladeStructureButtonPushed, true);
            app.BrowseBladeStructureButton.Text = 'Browse';
            app.BladeStructureEditFieldLabel = uilabel(app.StructuralAnalysisPanel);
            app.BladeStructureEditFieldLabel.Text = 'Blade Structure';
            app.BladeStructureEditField = uieditfield(app.StructuralAnalysisPanel, 'text');
            app.BrowseStructuralAnalysisOutputButton = uibutton(app.StructuralAnalysisPanel, 'push');
            app.BrowseStructuralAnalysisOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseStructuralAnalysisOutputButtonPushed, true);
            app.BrowseStructuralAnalysisOutputButton.Text = 'Browse';
            app.StructuralAnalysisOutputEditFieldLabel = uilabel(app.StructuralAnalysisPanel);
            app.StructuralAnalysisOutputEditFieldLabel.Text = 'Structural Analysis Output';
            app.StructuralAnalysisOutputEditField = uieditfield(app.StructuralAnalysisPanel, 'text');
            app.BrowseInputLoadsButton = uibutton(app.StructuralAnalysisPanel, 'push');
            app.BrowseInputLoadsButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputLoadsButtonPushed, true);
            app.BrowseInputLoadsButton.Text = 'Browse';
            app.InputLoadsEditFieldLabel = uilabel(app.StructuralAnalysisPanel);
            app.InputLoadsEditFieldLabel.Text = 'Input Loads';
            app.InputLoadsEditField = uieditfield(app.StructuralAnalysisPanel, 'text');
            app.GenerateDeflectionsButton = uibutton(app.StructuralAnalysisPanel, 'push');
            app.GenerateDeflectionsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDeflectionsButtonPushed, true);
            app.GenerateDeflectionsButton.Text = {'Generate'; 'Deflections'};
            app.GenerateDeflectionsTextArea = uitextarea(app.StructuralAnalysisPanel);
            app.GenerateEigenmodesButton = uibutton(app.StructuralAnalysisPanel, 'push');
            app.GenerateEigenmodesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateEigenmodesButtonPushed, true);
            app.GenerateEigenmodesButton.Text = {'Generate'; 'Eigenmodes'};
            app.GenerateEigenmodesTextArea = uitextarea(app.StructuralAnalysisPanel);
            app.PropertyGeneratorTab = uitab(app.TabGroup);
            app.PropertyGeneratorTab.Title = 'Property-Generator';
            app.PropertyGeneratorLabel = uilabel(app.PropertyGeneratorTab);
            app.PropertyGeneratorLabel.Text = 'Property-Generator';
            app.PropertyGeneratorOptionsPanel = uipanel(app.PropertyGeneratorTab);
            app.PropertyGeneratorOptionsPanel.Title = 'Options';
            app.Label = uilabel(app.PropertyGeneratorOptionsPanel);
            app.Label.Text = '';
            app.SimpleComplexSwitch = uiswitch(app.PropertyGeneratorOptionsPanel, 'slider');
            app.SimpleComplexSwitch.Items = {'Complex Geometry', 'Solid Geometry'};
            app.SimpleComplexSwitch.ValueChangedFcn = createCallbackFcn(app, @SimpleComplexSwitchValueChanged, true);
            app.SimpleComplexSwitch.Value = 'Complex Geometry';
            app.SwitchLabel = uilabel(app.PropertyGeneratorOptionsPanel);
            app.SwitchLabel.Text = '';
            app.TwistSwitch = uiswitch(app.PropertyGeneratorOptionsPanel, 'slider');
            app.TwistSwitch.Items = {'User-defined Twist', 'No Twist'};
            app.TwistSwitch.ValueChangedFcn = createCallbackFcn(app, @TwistSwitchValueChanged, true);
            app.TwistSwitch.Value = 'User-defined Twist';
            app.Label_2 = uilabel(app.PropertyGeneratorOptionsPanel);
            app.Label_2.Text = '';
            app.ChordSwitch = uiswitch(app.PropertyGeneratorOptionsPanel, 'slider');
            app.ChordSwitch.Items = {'User-defined Chord', 'Chord = 1'};
            app.ChordSwitch.ValueChangedFcn = createCallbackFcn(app, @ChordSwitchValueChanged, true);
            app.ChordSwitch.Value = 'User-defined Chord';
            app.Label_3 = uilabel(app.PropertyGeneratorOptionsPanel);
            app.Label_3.Text = '';
            app.SaveSwitch = uiswitch(app.PropertyGeneratorOptionsPanel, 'slider');
            app.SaveSwitch.Items = {'Save Properties to File', 'Output Only'};
            app.SaveSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveSwitchValueChanged, true);
            app.SaveSwitch.Value = 'Save Properties to File';
            app.GeneratePropertiesPanel = uipanel(app.PropertyGeneratorTab);
            app.GeneratePropertiesPanel.Title = 'Generate Properties';
            app.AirfoilEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.AirfoilEditFieldLabel.Text = 'Airfoil';
            app.AirfoilEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.BrowseAirfoilButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseAirfoilButtonPushed, true);
            app.BrowseAirfoilButton.Text = 'Browse';
            app.BrowseTwistButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseTwistButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTwistButtonPushed, true);
            app.BrowseTwistButton.Text = 'Browse';
            app.TwistDistributionEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.TwistDistributionEditFieldLabel.Text = 'Twist Distribution';
            app.TwistDistributionEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.BrowseChordButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseChordButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseChordButtonPushed, true);
            app.BrowseChordButton.Text = 'Browse';
            app.ChordDistributionEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.ChordDistributionEditFieldLabel.Text = 'Chord Distribution';
            app.ChordDistributionEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.BrowseNegativeSpaceButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseNegativeSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseNegativeSpaceButtonPushed, true);
            app.BrowseNegativeSpaceButton.Text = 'Browse';
            app.NegativeSpaceEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.NegativeSpaceEditFieldLabel.Text = 'Negative Space';
            app.NegativeSpaceEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.BrowseSaveAirfoilButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.BrowseSaveAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSaveAirfoilButtonPushed, true);
            app.BrowseSaveAirfoilButton.Text = 'Browse';
            app.PropertyGeneratorOutputEditFieldLabel = uilabel(app.GeneratePropertiesPanel);
            app.PropertyGeneratorOutputEditFieldLabel.Text = 'Property Generator Output';
            app.PropertyGeneratorOutputEditField = uieditfield(app.GeneratePropertiesPanel, 'text');
            app.GeneratePropertiesButton = uibutton(app.GeneratePropertiesPanel, 'push');
            app.GeneratePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePropertiesButtonPushed, true);
            app.GeneratePropertiesButton.Text = {'Generate'; 'Properties'};
            app.GeneratePropertiesTextArea = uitextarea(app.GeneratePropertiesPanel);
            app.XTurbBackendTab = uitab(app.TabGroup);
            app.XTurbBackendTab.Title = 'XTurb-Backend';
            app.XTurbBackendLabel = uilabel(app.XTurbBackendTab);
            app.XTurbBackendLabel.Text = 'XTurb-Backend';
            app.ConverterPanel = uipanel(app.XTurbBackendTab);
            app.ConverterPanel.Title = 'Converter';
            app.BrowseXTurb_OutputdatButton = uibutton(app.ConverterPanel, 'push');
            app.BrowseXTurb_OutputdatButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseXTurb_OutputdatButtonPushed, true);
            app.BrowseXTurb_OutputdatButton.Text = 'Browse';
            app.XTurb_OutputdatLabel = uilabel(app.ConverterPanel);
            app.XTurb_OutputdatLabel.Text = 'XTurb_Output[ ].dat';
            app.XTurb_OutputdatEditField = uieditfield(app.ConverterPanel, 'text');
            app.BrowseXTurbOutputButton = uibutton(app.ConverterPanel, 'push');
            app.BrowseXTurbOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseXTurbOutputButtonPushed, true);
            app.BrowseXTurbOutputButton.Text = 'Browse';
            app.XTurbOutputEditFieldLabel = uilabel(app.ConverterPanel);
            app.XTurbOutputEditFieldLabel.Text = 'XTurb Output';
            app.XTurbOutputEditField = uieditfield(app.ConverterPanel, 'text');
            app.ConvertButton = uibutton(app.ConverterPanel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.ConvertTextArea = uitextarea(app.ConverterPanel);
            app.Converter2Panel = uipanel(app.XTurbBackendTab);
            app.Converter2Panel.Title = 'Converter2';
            app.BrowseXTurbinpButton = uibutton(app.Converter2Panel, 'push');
            app.BrowseXTurbinpButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseXTurbinpButtonPushed, true);
            app.BrowseXTurbinpButton.Text = 'Browse';
            app.XTurbinpEditFieldLabel = uilabel(app.Converter2Panel);
            app.XTurbinpEditFieldLabel.Text = 'XTurb [ ].inp';
            app.XTurbinpEditField = uieditfield(app.Converter2Panel, 'text');
            app.BrowseXTurbOutput2Button = uibutton(app.Converter2Panel, 'push');
            app.BrowseXTurbOutput2Button.ButtonPushedFcn = createCallbackFcn(app, @BrowseXTurbOutput2ButtonPushed, true);
            app.BrowseXTurbOutput2Button.Text = 'Browse';
            app.XTurbOutputEditField_2Label = uilabel(app.Converter2Panel);
            app.XTurbOutputEditField_2Label.Text = 'XTurb Output';
            app.XTurbOutput2EditField = uieditfield(app.Converter2Panel, 'text');
            app.Convert2Button = uibutton(app.Converter2Panel, 'push');
            app.Convert2Button.ButtonPushedFcn = createCallbackFcn(app, @Convert2ButtonPushed, true);
            app.Convert2Button.Text = 'Convert';
            app.Convert2TextArea = uitextarea(app.Converter2Panel);
            app.XTurbBackend2Tab = uitab(app.TabGroup);
            app.XTurbBackend2Tab.Title = 'XTurb-Backend2';
            app.XTurbBackend2Label = uilabel(app.XTurbBackend2Tab);
            app.XTurbBackend2Label.Text = 'XTurb-Backend2';
            app.ExtractLoadsPanel = uipanel(app.XTurbBackend2Tab);
            app.ExtractLoadsPanel.Title = 'Extract Loads';
            app.BrowseExtractFromButton = uibutton(app.ExtractLoadsPanel, 'push');
            app.BrowseExtractFromButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseExtractFromButtonPushed, true);
            app.BrowseExtractFromButton.Text = 'Browse';
            app.ExtractFromEditFieldLabel = uilabel(app.ExtractLoadsPanel);
            app.ExtractFromEditFieldLabel.Text = 'Extract From';
            app.ExtractFromEditField = uieditfield(app.ExtractLoadsPanel, 'text');
            app.BrowseExtractOutputButton = uibutton(app.ExtractLoadsPanel, 'push');
            app.BrowseExtractOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseExtractOutputButtonPushed, true);
            app.BrowseExtractOutputButton.Text = 'Browse';
            app.ExtractOutputEditFieldLabel = uilabel(app.ExtractLoadsPanel);
            app.ExtractOutputEditFieldLabel.Text = 'Extract Output';
            app.ExtractOutputEditField = uieditfield(app.ExtractLoadsPanel, 'text');
            app.ExtractButton = uibutton(app.ExtractLoadsPanel, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.ExtractTextArea = uitextarea(app.ExtractLoadsPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PlotLabel = uilabel(app.PlotTab);
            app.PlotLabel.Text = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaceapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end